classdef appMLI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        AnalysisNoLabel             matlab.ui.control.Label
        ModalityDropDown            matlab.ui.control.DropDown
        ModalityDropDownLabel       matlab.ui.control.Label
        SortfeaturesCheckBox        matlab.ui.control.CheckBox
        PlotButton                  matlab.ui.control.Button
        SelectsubjectDropDown       matlab.ui.control.DropDown
        SelectsubjectDropDownLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ImagingMLIPanel             matlab.ui.container.Panel
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        TitleLabel                  matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cases 
        %curCase
        MLI
        featnames
        labels
        groupnames
        Ys
        curHandles
    end


    methods (Access = public)
        
        function updateFcn = func(app,newHandles)
            if ~isequal(app.curHandles, newHandles)
                app.startupFcn(app, newHandles)
            end
           

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles, varargin)

            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.curHandles = handles;
            app.AnalysisNoLabel.Text = sprintf("Analysis No. %d: %s", handles.curranal, handles.NM.analysis{1,handles.curranal}.id);
            
            if numel(varargin) ==0
                app.cases = handles.subjects;
                app.SelectsubjectDropDown.Items = app.cases;
                app.SelectsubjectDropDown.ItemsData = [app.cases];


                if length(handles.MLIdata.Label.BinResults.Modality)>1
                    app.ModalityDropDown.Visible = 1;
                    app.ModalityDropDownLabel.Visible = 1;
                    modlist = {};
                    for i = 1:length(handles.MLIdata.Label.BinResults.Modality)
                        modlist{i} = handles.NM.analysis{1,handles.curranal}.params.datadescriptor{1,i}.desc;
                    end
                    app.ModalityDropDown.Items = modlist;
                    app.ModalityDropDown.ItemsData = 1:length(modlist);
                else
                    app.ModalityDropDown.Visible = 0;

                end

                app.MLI = handles.MLIdata.Label.BinResults.Modality(1);
                app.featnames = handles.visdata{handles.curmodal,handles.curlabel}.params.features;
                %app.featnames = handles.NM.featnames; %varargin{1};
                %app.featnames = handles.NM.featnames{1,1}';
                app.labels = handles.NM.label;
                app.groupnames = handles.NM.groupnames;
                app.Ys = handles.NM.Y;


                curModality = app.MLI.modality_num;
                curModalityType = app.MLI.modality_type;


                curCaseID = app.SelectsubjectDropDown.Value;
                DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
                curCaseIdx = find(strcmp(DDitemsdataMatChar,curCaseID));
                grouplabel = app.labels;
                groupnames = app.groupnames;
                g = grouplabel(curCaseIdx);
                case_groupname = char(groupnames(g));
                app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', curCaseID, case_groupname);
                
                if curModalityType %1 = imaging
                    app.ImagingMLIPanel.Visible = 1;
                    global stMLI

                    stMLI.ParentFig = app.UIFigure;
                    stMLI.fig = app.ImagingMLIPanel; 
                    stMLI.NMaxes = [ app.UIAxes3 app.UIAxes4 app.UIAxes5];

                    imagingMLI(curCaseIdx, app.MLI);

                else
                    barMLI(curCaseIdx, app.MLI, ...
                        app.featnames, ...
                        app.Ys{1,curModality}, ...
                        app.SortfeaturesCheckBox.Value, ...
                        app.UIAxes, ...
                        app.UIAxes2);
                end

               
            else
                app.PlotButton.Visible = 0;
                app.SortfeaturesCheckBox.Visible = 0;
                app.SelectsubjectDropDownLabel.Visible = 0;
                app.SelectsubjectDropDown.Visible = 0;
                app.ModalityDropDown.Visible = 0;
                curModality = handles.curmodal;
                app.cases = handles.subjects;
                app.SelectsubjectDropDown.Items = app.cases;
                app.SelectsubjectDropDown.ItemsData = [app.cases];
                curCaseIdx = varargin{1};
                DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
                curCaseID = DDitemsdataMatChar(curCaseIdx);
                
                grouplabel = handles.NM.label;
                groupnames = handles.NM.groupnames;
                g = grouplabel(curCaseIdx);
                case_groupname = char(groupnames(g));
                app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', string(curCaseID), case_groupname);

                barMLI(curCaseIdx, handles.MLIdata.Label.BinResults.Modality(1), ...
                    handles.NM.featnames, ...
                    handles.NM.Y{1,curModality}, ...
                    app.SortfeaturesCheckBox.Value, ...
                    app.UIAxes, ...
                    app.UIAxes2);

            end
       
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);

           
            curCaseID = app.SelectsubjectDropDown.Value;
            DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
            curCaseIdx = find(strcmp(DDitemsdataMatChar,curCaseID));
 

            if app.ModalityDropDown.Visible == 1
                curModality = app.ModalityDropDown.Value; 
            else
                curModality = 1;
            end

            grouplabel = app.labels;
            g = grouplabel(curCaseIdx);
            groupnames = app.groupnames;
            case_groupname = char(groupnames(g));
            %app.UIAxes.Title.String = sprintf('Predictive profile of subject #%s (Group: %s)', curCaseID, case_groupname);
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', curCaseID, case_groupname);
            barMLI(curCaseIdx, app.MLI, ...
                app.featnames, ...
                app.Ys{1,curModality}, ... 
                app.SortfeaturesCheckBox.Value, ...
                app.UIAxes, ...
                app.UIAxes2);
        end

        % Drop down opening function: SelectsubjectDropDown
        function SelectsubjectDropDownOpening(app, event)
            
        end

        % Value changed function: SelectsubjectDropDown
        function SelectsubjectDropDownValueChanged(app, event)
            value = app.SelectsubjectDropDown.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {807, 807};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectsubjectDropDownLabel = uilabel(app.LeftPanel);
            app.SelectsubjectDropDownLabel.Text = 'Select subject';
            app.SelectsubjectDropDown = uidropdown(app.LeftPanel);
            app.SelectsubjectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectsubjectDropDownOpening, true);
            app.SelectsubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsubjectDropDownValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SortfeaturesCheckBox = uicheckbox(app.LeftPanel);
            app.SortfeaturesCheckBox.Text = 'Sort features';
            app.ModalityDropDownLabel = uilabel(app.LeftPanel);
            app.ModalityDropDownLabel.Visible = 'off';
            app.ModalityDropDownLabel.Text = 'Modality';
            app.ModalityDropDown = uidropdown(app.LeftPanel);
            app.ModalityDropDown.Visible = 'off';
            app.AnalysisNoLabel = uilabel(app.LeftPanel);
            app.AnalysisNoLabel.Text = 'Analysis No.';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TitleLabel = uilabel(app.RightPanel);
            app.TitleLabel.Text = 'Title';
            app.ImagingMLIPanel = uipanel(app.RightPanel);
            app.ImagingMLIPanel.Enable = 'off';
            app.ImagingMLIPanel.Title = 'ImagingMLI';
            app.ImagingMLIPanel.Visible = 'off';
            app.UIAxes3 = uiaxes(app.ImagingMLIPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ImagingMLIPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.ImagingMLIPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMLI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end