classdef appSpiderPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIMapperUIFigure         matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ConfigPanel               matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        AnalysisTab               matlab.ui.container.Tab
        AnalysesListBox           matlab.ui.control.ListBox
        AnalysesListBoxLabel      matlab.ui.control.Label
        PatternDropDown           matlab.ui.control.DropDown
        PatternDropDownLabel      matlab.ui.control.Label
        OutputresultsPanel        matlab.ui.container.Panel
        SelectdirButton           matlab.ui.control.Button
        OutputPathEditField       matlab.ui.control.EditField
        ThresholdingPanel         matlab.ui.container.Panel
        OperatorLabel             matlab.ui.control.Label
        MinimumVoxPercEditField   matlab.ui.control.NumericEditField
        MinimumLabel              matlab.ui.control.Label
        ThresholdtypeDropDown     matlab.ui.control.DropDown
        Threshold2EditField       matlab.ui.control.NumericEditField
        Threshold2EditFieldLabel  matlab.ui.control.Label
        Threshold1EditField       matlab.ui.control.NumericEditField
        Threshold1EditFieldLabel  matlab.ui.control.Label
        TwothresholdmodeCheckBox  matlab.ui.control.CheckBox
        AtlasingPanel             matlab.ui.container.Panel
        ROInameDropDown           matlab.ui.control.DropDown
        ROInameDropDownLabel      matlab.ui.control.Label
        ROIIDDropDown             matlab.ui.control.DropDown
        ROIIDDropDownLabel        matlab.ui.control.Label
        ReadtableheaderButton     matlab.ui.control.Button
        DelimiterDropDown         matlab.ui.control.DropDown
        DelimiterDropDownLabel    matlab.ui.control.Label
        SelectROIButton           matlab.ui.control.Button
        ROIfileEditField          matlab.ui.control.EditField
        ROIfileEditFieldLabel     matlab.ui.control.Label
        SelectAtlasButton         matlab.ui.control.Button
        AtlasfileEditField        matlab.ui.control.EditField
        AtlasfileEditFieldLabel   matlab.ui.control.Label
        PlottingTab               matlab.ui.container.Tab
        PlotButton                matlab.ui.control.Button
        AnalyzeButton             matlab.ui.control.Button
        PlotPanel                 matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pimg
        patterndesc
        patternfields
        patternnames
        visdata
        handles
        atlas % atlas image
        roiList % atlas regions csv
        roiT % atlas regions table
        mniIDX1
        mniIDX2
        options
        meas
        measind
        vROI
        tROI
        yROI
        yT
    end
    
    methods (Access = private)
        
        function [popuplist, popupitems] = PopulatePatternDropDown(app)
            analind = app.AnalysesListBox.Value;
            nA = numel(analind);
            patterncounter = zeros(1, numel(app.patternfields));
            for i=1:nA
                v = app.visdata{i};
                fldnames = fieldnames(v{1});
                idx = ismember(app.patternfields, fldnames);
                patterncounter(idx) = patterncounter(idx)+1;
            end
            patternbool = patterncounter == numel(analind);
            popuplist = app.patterndesc(patternbool);
            popupitems = app.patternfields(patternbool);
        end
        
        function [app, popuplist, popupitems] = PopulateAnalysesDropDown(app)
            AS = nk_GetAnalysisStatus(app.handles.NM);
            idx = AS.analyses_visualized & ~cellfun(@isempty,AS.analyses_imaging);
            f_idx = find(idx);
            popuplist = cell(1, numel(f_idx));
            for i=1:numel(f_idx)
                popuplist{i} = sprintf('%g: %s', f_idx(i), app.handles.NM.analysis{f_idx(i)}.id);
                app.visdata{i} = app.handles.NM.analysis{f_idx(i)}.visdata;
            end
            popupitems = f_idx;
        end
        
        function app = WritePatterns2Disk(app)
            app.options.name = fullfile(app.OutputPathEditField.Value,'ROImapper');
            if ~exist(app.options.name,"dir"), mkdir(app.options.name); end
            pattern_field = app.PatternDropDown.Value;
            cnt=1;
            for i=1:numel(app.AnalysesListBox.Value)
                idx_A = app.AnalysesListBox.Value(i);
                [nF, nL] = size(app.visdata{idx_A});
                for j=1:nF
                    idx_F = app.visdata{idx_A}{j,1}.params.varind;
                    brainmask   = app.handles.NM.brainmask{idx_F};
                    badcoords   = app.handles.NM.badcoords{idx_F};
                    labelthr    = app.handles.NM.datadescriptor{idx_F}.threshval;
                    labelop     = app.handles.NM.datadescriptor{idx_F}.threshop;
                    for k=1:nL
                        img = cell2mat(app.visdata{i}{j,k}.(pattern_field));
                        app.options.pimg{cnt} = fullfile(app.options.name,sprintf('%s_A%g_M%g_L%g', pattern_field, idx_A, idx_F, k));
                        nk_WriteVol(img, app.options.pimg{cnt}, 2, brainmask, badcoords, labelthr, char(labelop));
                        app.options.pimg{cnt} = [app.options.pimg{cnt} '.nii'];
                        cnt=cnt+1;
                    end
                end
            end
            app.pimg = char(app.options.pimg);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles)
            
            % Start with definitions
            app.patterndesc = {'Feature weights [Overall Mean (StErr)]', ...
                        'Feature weights [Grand Mean (StErr)]', ...
                        'CV-ratio of feature weights [Overall Mean]', ...
                        'CV-ratio of feature weights [Grand Mean]', ...
                        'Feature selection probability [Overall Mean]', ...
                        'Probability of feature reliability (95%-CI) [Grand Mean]', ...
                        'Sign-based consistency', ...
                        'Sign-based consistency (Z score)', ...
                        'Sign-based consistency -log10(P value)', ...
                        'Sign-based consistency -log10(P value, FDR)', ...
                        'Spearman correlation [Grand Mean]', ...
                        'Pearson correlation [Grand Mean]', ...
                        'Spearman correlation -log10(P value) [Grand Mean]', ...
                        'Pearson correlation -log10(P value) [Grand Mean]', ...
                        'Spearman correlation -log10(P value, FDR) [Grand Mean]', ...
                        'Pearson correlation -log10(P value, FDR) [Grand Mean]', ...
                        'Permutation-based -log10(P value) [Grand Mean]', ...
                        'Permutation-based -log10(P value, FDR) [Grand Mean]', ...
                        'Permutation-based Z Score [Grand Mean]', ...
                        'Analytical -log10(P Value) for Linear SVM [Grand Mean]', ...
                        'Analytical -log10(P Value, FDR) for Linear SVM [Grand Mean]'};

            app.patternfields = {'MEAN', ...
                        'MEAN_CV2', ...
                        'CVRatio', ...
                        'CVRatio_CV2', ...
                        'FeatProb', ...
                        'Prob_CV2', ...
                        'SignBased_CV2', ...
                        'SignBased_CV2_z', ...
                        'SignBased_CV2_p_uncorr', ...
                        'SignBased_CV2_p_fdr', ...
                        'Spearman_CV2', ...
                        'Pearson_CV2', ...
                        'Spearman_CV2_p_uncorr', ...
                        'Pearson_CV2_p_uncorr', ...
                        'Spearman_CV2_p_fdr', ...
                        'Pearson_CV2_p_fdr', ...
                        'PermProb_CV2', ...
                        'PermProb_CV2_FDR_PVAL', ...
                        'PermZ_CV2', ...
                        'Analytical_p', ...
                        'Analyitcal_p_fdr'};

            app.patternnames = {'FeatWeights_overallM', ...
                        'FeatWeights_grandM', ...
                        'CV_featWeights_overallM', ...
                        'CV_featWeights_grandM', ...
                        'ProbFeat_overallM', ...
                        'ProbFeatRel95_grandM', ...
                        'SignBsdConsistency', ....
                        'SignBsdConsistency_ZScore', ...
                        'SignBsdConsistency_log10Pval', ...
                        'SignBsdConsistency_log10Pval_FDR', ...
                        'Spearman_grandM', ...
                        'Pearson_grandM', ...
                        'Spearman_grandM_log10Pval', ...
                        'Pearson_grandM_log10Pval', ...
                        'Spearman_grandM_log10Pval_FDR', ...
                        'Pearson_grandM_log10Pval_FDR', ...
                        'Perm_log10Pval', ...
                        'Perm_log10Pval_FDR', ...
                        'Perm_Z', ...
                        'AnalyticalP_log10Pval', ...
                        'AnalyticalP_log10Pval_FDR'};

            app.handles = handles; 
            % for now, this is the default atlas
            app.atlas = sprintf('%s/interpretable/anatomical/AAL3.nii', handles.NM.defs.paths);
            app.AtlasfileEditField.Value = app.atlas;
            app.roiList = sprintf('%s/interpretable/anatomical/AAL3.csv', handles.NM.defs.paths);
            app.ROIfileEditField.Value = app.roiList;
            app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', ";");
            app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROIIDDropDown.Value=1;
            app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROInameDropDown.Value=2;
            
            [app, app.AnalysesListBox.Items, app.AnalysesListBox.ItemsData] = PopulateAnalysesDropDown(app);
            [app.PatternDropDown.Items, app.PatternDropDown.ItemsData] = PopulatePatternDropDown(app);

            % user input
            app.options = struct;
            app.options.vxcheck = 1;
            app.options.thresh = app.Threshold1EditField.Value;
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;

            app.OutputPathEditField.Value = handles.NM.analysis{1,handles.curranal}.rootdir; % does rootdir exist?
            if isfield(handles,'selVisMeas')
                app.measind = handles.selVisMeas.Value;
                app.meas = handles.selVisMeas.String;
                app.PatternDropDown.Value = app.patternfields{strcmp(app.patterndesc, app.meas{app.measind})};
            else
                app.measind = 1;
                app.meas = app.patterndesc{1};
            end
            app.options.pattern_index = strcmp(app.patterndesc, app.meas{app.measind});
            app.options.pattern_name = app.patternnames{app.options.pattern_index};
            app.options.pattern_field = app.patternfields{app.options.pattern_index};
            app.options.data = [];
            app.options.compute_voxelstats = 0;
            app.TwothresholdmodeCheckBox.Value = 0;

            %             spider_plot(P, ...
%                 'axesLabels', rois.Var2,...
%                 'axesLimits', [zeros(1,y); 100*ones(1,y)], ...
%                 'axesInterval', 1, ...
%                 'axesLabelsEdge','none', ...
%                 'axesDisplay', 'one', ...
%                 'axesLabelsOffset', 0.05, ...
%                 'axesFontSize', 12,...
%                 'labelFontSize', 9, ...
%                 'lineWidth',2,...
%                 'fillOption', 'on',...
%                 'markerSize', 18,...
%                 'axesHorzAlign', 'quadrant', ...
%                 'axesVertAlign', 'quadrant', ...
%                 'axesLabelsRotate', 'on', ...
%                 'axesDirection', 'normal', ...
%                 'axesPrecision',0,...
%                 'axesZero','on',...
%                 'axesLabelsRotate','on',...
%                 'axesOffset',1, ...
%                 'axesobject', app.UIAxes);

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            app.options = struct;
            app.options.vxcheck = 1;
            if app.TwothresholdmodeCheckBox.Value
                app.options.thresh = [app.Threshold1EditField.Value app.Threshold2EditField.Value];
            else
                app.options.thresh = app.Threshold1EditField.Value;
            end
            app.options.typthresh = app.ThresholdtypeDropDown.Value;
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;
            app.options.pattern_index = strcmp(app.patternfields, app.PatternDropDown.Value);
            app.options.pattern_name = app.patterndesc{app.options.pattern_index};
            app.options.pattern_field = app.patternfields{app.options.pattern_index};
            app.options.data = [];
            app.options.compute_voxelstats = 0;

            app = WritePatterns2Disk(app);

            [app.vROI, app.tROI, app.yROI, app.yT] = nk_ROImapper(app.pimg, ...
                [], ...
                app.atlas, ...
                app.roiList, ...
                app.ROIIDDropDown.Value, ...
                app.ROInameDropDown.Value, ...
                app.options);

            app.PlotButton.Enable="on";
            app.UIAxes.Visible="off";
            drawnow
        end

        % Value changed function: TwothresholdmodeCheckBox
        function TwothresholdmodeCheckBoxValueChanged(app, event)
            value = app.TwothresholdmodeCheckBox.Value;
            if value
                 app.ThresholdtypeDropDown.Items = {'<|>','<=|>=','>|<','>=|<='};
                 app.ThresholdtypeDropDown.ItemsData = 1:4;
                 app.Threshold2EditField.Enable = "on";
                 app.Threshold2EditFieldLabel.Enable = "on";
            else
                 app.ThresholdtypeDropDown.Items = {'<','<=','>','>=','=='};
                 app.ThresholdtypeDropDown.ItemsData = 1:5;
                 app.Threshold2EditField.Enable = "off";
                 app.Threshold2EditFieldLabel.Enable = "off";
            end
            drawnow
        end

        % Button pushed function: SelectAtlasButton
        function SelectAtlasButtonPushed(app, event)
            if isempty(app.AtlasfileEditField.Value)
                defatlas = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.nii');
            else
                defatlas = app.AtlasfileEditField.Value;
            end
            [atlas_file,atlas_path] = uigetfile({'*.nii','NIfTI-files (*.nii)'; ...
                '*.img','NIftI/ANALYZE images (*.img)'},'Select atlas image file', defatlas);
            app.atlas = fullfile(atlas_path,atlas_file);
            app.AtlasfileEditField.Value = app.atlas;
            app.AtlasfileEditField.Enable = "on";
            drawnow
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if isempty(app.ROIfileEditField.Value)
                defroi = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.csv');
            else
                defroi = app.ROIfileEditField.Value;
            end
            [roi_file, roi_path] = uigetfile({'*.csv','CSV-files (*.csv)'; ...
                '*.txt','Text files (*.txt)'},'Select atlas ROI descriptor file', defroi);
            app.roiList = fullfile(roi_path,roi_file);
            app.ROIfileEditField.Value = app.roiList;
            app.ROIfileEditField.Enable = "on";
            app.DelimiterDropDown.Enable = "on";
            app.ReadtableheaderButton.Enable = "on";
            drawnow
        end

        % Button pushed function: ReadtableheaderButton
        function ReadtableheaderButtonPushed(app, event)
            delimiters = {';',',','space','tab'};
            value = app.DelimiterDropDown.Value;
            try
                 app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', delimiters{value});
            catch ERR
                errordlg(sprintf('The atlas descriptor file %s could not be read!\nError: %s\nCheck your settings!', ...
                    app.ROIfileEditField.Value, ERR.message), 'Spider plot');
            end
            if ~isempty(app.roiT)
                if numel(app.roiT.Properties.VariableNames)>1
                        app.ROIIDDropDown.Enable="on";
                        app.ROInameDropDown.Enable="on";
                        app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                        app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                else
                    app.ROIIDDropDown.Enable="off";
                    app.ROInameDropDown.Enable="off";
                end
            end
            drawnow
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot_spider_plot(app.tROI, app.roiT, app.UIAxes);
            app.UIAxes.Visible="on";
            drawnow
        end

        % Value changed function: Threshold1EditField
        function Threshold1EditFieldValueChanged(app, event)
            value = app.Threshold1EditField.Value;
            
        end

        % Value changed function: DelimiterDropDown
        function DelimiterDropDownValueChanged(app, event)
            value = app.DelimiterDropDown.Value;
            
        end

        % Button pushed function: SelectdirButton
        function SelectdirButtonPushed(app, event)
            app.OutputPathEditField.Value = uigetdir(app.handles.NM.analysis{app.handles.curranal}.rootdir);
        end

        % Value changed function: AnalysesListBox
        function AnalysesListBoxValueChanged(app, event)
            value = app.AnalysesListBox.Value;
            [app.PatternDropDown.Items, app.PatternDropDown.ItemsData] = PopulatePatternDropDown(app);
            app.options.analyses = app.AnalysesListBox.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ROIMapperUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {795, 795};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIMapperUIFigure = uifigure('Visible', 'off');
            app.ROIMapperUIFigure.Name = 'ROI Mapper';
            app.ROIMapperUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ROIMapperUIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Tooltip = {''};
            app.ConfigPanel.Title = 'Configuration';
            app.AnalyzeButton = uibutton(app.ConfigPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.PlotButton = uibutton(app.ConfigPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.ConfigPanel);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AtlasingPanel = uipanel(app.AnalysisTab);
            app.AtlasingPanel.Title = 'Atlasing';
            app.AtlasfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.AtlasfileEditFieldLabel.Enable = 'off';
            app.AtlasfileEditFieldLabel.Text = 'Atlas file';
            app.AtlasfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.AtlasfileEditField.Enable = 'off';
            app.AtlasfileEditField.Tooltip = {'path to parcellation image <char>'};
            app.SelectAtlasButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasButtonPushed, true);
            app.SelectAtlasButton.Text = 'Select Atlas';
            app.ROIfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.ROIfileEditFieldLabel.Enable = 'off';
            app.ROIfileEditFieldLabel.Text = 'ROI file';
            app.ROIfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.ROIfileEditField.Enable = 'off';
            app.ROIfileEditField.Tooltip = {'path to csv or excel file with parcellation info, which should contain the the id column indexing parcellations '; 'in the parcellation image <char>'};
            app.SelectROIButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.DelimiterDropDownLabel = uilabel(app.AtlasingPanel);
            app.DelimiterDropDownLabel.Enable = 'off';
            app.DelimiterDropDownLabel.Text = 'Delimiter';
            app.DelimiterDropDown = uidropdown(app.AtlasingPanel);
            app.DelimiterDropDown.Items = {'semicolon', 'comma', 'space', 'tabulator'};
            app.DelimiterDropDown.ItemsData = [1 2 3 4];
            app.DelimiterDropDown.Editable = 'on';
            app.DelimiterDropDown.ValueChangedFcn = createCallbackFcn(app, @DelimiterDropDownValueChanged, true);
            app.DelimiterDropDown.Enable = 'off';
            app.DelimiterDropDown.Value = 1;
            app.ReadtableheaderButton = uibutton(app.AtlasingPanel, 'push');
            app.ReadtableheaderButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtableheaderButtonPushed, true);
            app.ReadtableheaderButton.Enable = 'off';
            app.ReadtableheaderButton.Text = 'Read table header';
            app.ROIIDDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROIIDDropDownLabel.Enable = 'off';
            app.ROIIDDropDownLabel.Text = 'ROI ID';
            app.ROIIDDropDown = uidropdown(app.AtlasingPanel);
            app.ROIIDDropDown.Enable = 'off';
            app.ROInameDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROInameDropDownLabel.Enable = 'off';
            app.ROInameDropDownLabel.Text = 'ROI name';
            app.ROInameDropDown = uidropdown(app.AtlasingPanel);
            app.ROInameDropDown.Enable = 'off';
            app.ThresholdingPanel = uipanel(app.AnalysisTab);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.TwothresholdmodeCheckBox = uicheckbox(app.ThresholdingPanel);
            app.TwothresholdmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TwothresholdmodeCheckBoxValueChanged, true);
            app.TwothresholdmodeCheckBox.Text = 'Two-threshold mode';
            app.Threshold1EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold1EditFieldLabel.Text = 'Threshold 1';
            app.Threshold1EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.Threshold1EditField.ValueChangedFcn = createCallbackFcn(app, @Threshold1EditFieldValueChanged, true);
            app.Threshold2EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold2EditFieldLabel.Text = 'Threshold 2';
            app.Threshold2EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.ThresholdtypeDropDown = uidropdown(app.ThresholdingPanel);
            app.ThresholdtypeDropDown.Items = {'<', '<=', '>', '>=', '=='};
            app.ThresholdtypeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ThresholdtypeDropDown.Value = '1';
            app.MinimumLabel = uilabel(app.ThresholdingPanel);
            app.MinimumLabel.Tooltip = {''};
            app.MinimumLabel.Text = 'Minimum % of voxels in ROI  ';
            app.MinimumVoxPercEditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.MinimumVoxPercEditField.Value = 1;
            app.OperatorLabel = uilabel(app.ThresholdingPanel);
            app.OperatorLabel.Text = 'Operator';
            app.OutputresultsPanel = uipanel(app.AnalysisTab);
            app.OutputresultsPanel.Title = 'Output results';
            app.OutputPathEditField = uieditfield(app.OutputresultsPanel, 'text');
            app.SelectdirButton = uibutton(app.OutputresultsPanel, 'push');
            app.SelectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirButtonPushed, true);
            app.SelectdirButton.Text = 'Select dir';
            app.PatternDropDownLabel = uilabel(app.AnalysisTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.AnalysisTab);
            app.AnalysesListBoxLabel = uilabel(app.AnalysisTab);
            app.AnalysesListBoxLabel.Text = 'Analyses';
            app.AnalysesListBox = uilistbox(app.AnalysisTab);
            app.AnalysesListBox.Multiselect = 'on';
            app.AnalysesListBox.ValueChangedFcn = createCallbackFcn(app, @AnalysesListBoxValueChanged, true);
            app.AnalysesListBox.Value = {'Item 1'};
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.ROIMapperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSpiderPlot(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ROIMapperUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ROIMapperUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIMapperUIFigure)
        end
    end
end