classdef simAppAux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OkButton                       matlab.ui.control.Button
        addtooriginaldataCheckBox      matlab.ui.control.CheckBox
        SimSpinner                     matlab.ui.control.Spinner
        NsimulationroundsSpinnerLabel  matlab.ui.control.Label
        HeaderLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app simSample_App2
        EditFields
        add2orig
        NGroups
        NSims
        simGrid
    end
    
    methods (Access = private)
        
        function updateGrid(app, NGroups, NSims, simGrid)

   
            for i=1:NGroups
                for j=1:NSims
                    v = 300 - 30*i;
                    h = 50 + 60*j;
                    eval(sprintf('app.EditFields.Group%dSim%d = uieditfield(app.UIFigure,''numeric'', ''Position'',[h,v,50,20], ''Limits'', [0 Inf]);', i,j));
                end
            end

            if ~isempty(simGrid)
                simVec = reshape(simGrid',1,numel(simGrid));
                fns = fieldnames(app.EditFields);
                for i=1:length(fns)
                    thisEditField = app.EditFields.(fns{i});
                    thisEditField.Value = simVec(i);
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NSims, NGroups, simGrid, add2orig)
            app.CallingApp = mainapp;
            app.addtooriginaldataCheckBox.Value = add2orig; % set check box label to value it has previously been set to
            app.SimSpinner.Value = NSims; 
            app.NGroups = NGroups;
            app.NSims = NSims; 
            app.simGrid = simGrid;
            updateGrid(app, NGroups, NSims, simGrid);
            % initial grid 
    
        end

        % Value changed function: SimSpinner
        function SimSpinnerValueChanged(app, event)
            app.NSims = app.SimSpinner.Value;

         
            updateGrid(app, app.NGroups, app.NSims, app.simGrid)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            values = zeros(1,numel(app.EditFields));
            fns = fieldnames(app.EditFields);
            for i=1:length(fns)
                thisEditField = app.EditFields.(fns{i});
                values(i) = thisEditField.Value;
            end
            
            simGrid = reshape(values, [app.NGroups,app.NSims]);
            simGrid = simGrid';
            app.add2orig = app.addtooriginaldataCheckBox.Value;

            updateSimPlan(app.CallingApp, simGrid, app.add2orig);

            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SetupButton.Enable = 'on';
            app.CallingApp.SimulateButton_post.Enable = 'on';
            
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Setup the simulation rounds';
            app.NsimulationroundsSpinnerLabel = uilabel(app.UIFigure);
            app.NsimulationroundsSpinnerLabel.Text = 'N simulation rounds';
            app.SimSpinner = uispinner(app.UIFigure);
            app.SimSpinner.Limits = [1 Inf];
            app.SimSpinner.ValueChangedFcn = createCallbackFcn(app, @SimSpinnerValueChanged, true);
            app.SimSpinner.Value = 1;
            app.addtooriginaldataCheckBox = uicheckbox(app.UIFigure);
            app.addtooriginaldataCheckBox.Text = 'add to original data';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simAppAux(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end