classdef simSample_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportfigureButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        priorTab                        matlab.ui.container.Tab
        SimulateButton                  matlab.ui.control.Button
        NcasesEditField                 matlab.ui.control.EditField
        NcasesEditFieldLabel            matlab.ui.control.Label
        MLmodelEditField                matlab.ui.control.EditField
        MLmodelEditFieldLabel           matlab.ui.control.Label
        NAperfeatureEditField           matlab.ui.control.NumericEditField
        NAperfeatureEditFieldLabel      matlab.ui.control.Label
        NApercaseEditField              matlab.ui.control.NumericEditField
        NApercaseEditFieldLabel         matlab.ui.control.Label
        BatchPercEditField              matlab.ui.control.NumericEditField
        BatchEditFieldLabel             matlab.ui.control.Label
        NbatchesEditField               matlab.ui.control.NumericEditField
        NbatchesEditFieldLabel          matlab.ui.control.Label
        minAUCEditField                 matlab.ui.control.NumericEditField
        minAUCLabel                     matlab.ui.control.Label
        maxAUCEditField                 matlab.ui.control.NumericEditField
        maxAUCEditFieldLabel            matlab.ui.control.Label
        RelativedistributionlabelsEditField  matlab.ui.control.NumericEditField
        RelativedistributionlabelsEditFieldLabel  matlab.ui.control.Label
        predictivemarkersEditField      matlab.ui.control.NumericEditField
        predictivemarkersEditFieldLabel  matlab.ui.control.Label
        NfeaturesEditField              matlab.ui.control.NumericEditField
        NfeaturesEditFieldLabel         matlab.ui.control.Label
        postTab                         matlab.ui.container.Tab
        AnalysisListBox                 matlab.ui.control.ListBox
        AnalysistosimulateListBoxLabel  matlab.ui.control.Label
        SetupButton                     matlab.ui.control.Button
        TextField_SimulationOverview    matlab.ui.control.TextArea
        SimulateButton_post             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simAppAux
        data % Description
        loadedNMData
        NM_Ys
        %NM_Y
        NM_analyses
        %NM_label
        NM
        add2orig = 0 %add data to original 1 or 0
        NSims = 1
        NGroups 
        NperGroupRound = 0 
        simGrid
        simPlanGrid
     
    end    
    
    properties (Access = public)
        NM_Y % Description
    end

    
    methods (Access = private)
        function [app, popuplist, popupitems] = PopulateAnalysesDropDown(app)
            AS = nk_GetAnalysisStatus(app.NM);
            idx = AS.completed_analyses;
            f_idx = find(idx);
            popuplist = cell(1, numel(f_idx));
            for i=1:numel(f_idx)
                popuplist{i} = sprintf('%g: %s', f_idx(i), app.NM.analysis{f_idx(i)}.id);
            end
            popupitems = f_idx;
        end

    end
    
    methods (Access = public)
        
        function updateSimPlan(app, simGrid, add2orig)
            
            %app.simPlanGrid = uitable(app.postTab, 'Data', simGrid, 'Position', [270,50, 200,130]);
            text = sprintf('SIMULATION PLAN: \n%d ROUNDS\n',size(simGrid,2));
            for i=1:size(simGrid,1)
                line = sprintf('Group %d: ', i);
                for j=1:size(simGrid,2)
                    line = sprintf('%s %d', line, simGrid(i,j));
                end
                text = sprintf('%s\n', text, line);
            end
            text = sprintf('%s\n')
            app.TextField_SimulationOverview.Value = text;

            app.simGrid = simGrid;
            app.add2orig = add2orig;

            app.SetupButton.Enable = 'on';
            app.SimulateButton_post.Enable = 'on';
            app.NSims = size(simGrid,2);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin > 1
                app.NM = varargin(1);
                app.NM = app.NM{1};
                %app.NM_Ys = app.NM.Y;
                %app.NM_analyses = app.NM.analysis;

                [app, app.AnalysisListBox.Items, app.AnalysisListBox.ItemsData] = PopulateAnalysesDropDown(app);
                
                app.SimulateButton_post.Enable = 'off';

                
                %app.NM_label = app.NM.label;
                app.loadedNMData = 1;
                app.TextField_SimulationOverview.Visible = 1;
                % n groups
                app.NGroups = length(app.NM.groupnames);
            else 
                app.loadedNMData = 0;
                app.postTab.HandleVisibility.Visible = 'off';
            end
            drawnow
        end

        % Value changed function: NfeaturesEditField
        function NfeaturesEditFieldValueChanged(app, event)
          
        end

        % Value changed function: predictivemarkersEditField
        function predictivemarkersEditFieldValueChanged(app, event)
      
        end

        % Value changed function: RelativedistributionlabelsEditField
        function RelativedistributionlabelsEditFieldValueChanged(app, event)
          
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            IN.Nfeats = app.NfeaturesEditField.Value;
            IN.Nmarkers = app.predictivemarkersEditField.Value;
            IN.Ncases = str2num(app.NcasesEditField.Value);
            IN.eventprob = app.RelativedistributionlabelsEditField.Value;
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.algorithm = str2num(app.MLmodelEditField.Value);
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            %app.ResultsTextArea = evalc(simSamp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.withlabelDropDown.Value;
            switch value
                case 'posthoc'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
                case 'prior'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
            end
        end

        % Callback function
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            app.DatasetNoEditField.Value = fullfile(path, file);
            
            app.data = readtable(fullfile(path,file));
            app.TextArea.Visible = 'on';
            %infotext = {};
            infotext = sprintf('Dataset: \nN features: %d \nN observations: %d\n\n', ...
                size(app.data,2), size(app.data,1));
            if isempty(app.LabelvariableEditField.Value)
                varinfo = sprintf('Variable names:\n');
                for i = 1:numel(app.data.Properties.VariableNames)
                    varinfo = [varinfo, sprintf('%s\n', ...
                        app.data.Properties.VariableNames{1,i})];
                end
                infotext = [infotext, varinfo];

                %else
             %   n_groups = 
             %   infotext = {sprintf()}
            end

            
            app.TextArea.Value = infotext;
            app.LabelvariableEditField.Editable = 'on';
            app.ExcludeCheckBox.Enable = 'on';
       
        end

        % Callback function
        function SimulateButton_postPushed(app, event)
            % prepare dataset
            % check if variables (e.g., ID) should be excluded

            if app.loadedNMData
                M = app.NM.analysis{1,app.AnalysistosimulateEditField.Value}.params.TrainParam.FUSION.M;
                app.NM_Y = app.NM_Ys{1,M};
                feat = app.NM_Y;
                labels = app.NM_label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            IN.Data = feat; 
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.cases = app.simGrid;
            %IN.Ncases = str2num(app.NcasesEditField_post.Value);
            IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
            IN.eventprob = app.RelativedistributionlabelsEditField.Value; %unused
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.add2orig = 0;
            %IN.algorithm = 
            IN.NRanalysis = app.AnalysistosimulateEditField.Value;
            IN.verbose = 0;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end

        % Callback function
        function ExcludeCheckBoxValueChanged(app, event)
            value = app.ExcludeCheckBox.Value;
            if value
                app.ExcludeEditField.Visible = 'on';
            else 
                app.ExcludeEditField.Visible = 'off';
            end
        end

        % Button pushed function: ExportfigureButton
        function ExportfigureButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIAxes,fullfile(filepath,filename));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Callback function
        function OkButtonPushed(app, event)
            app.TextField_SimulationOverview.Value = sprintf('Analysis %d, %s selected.\n', ...
                app.AnalysistosimulateEditField.Value, ...
                app.NM_analyses{1,app.AnalysistosimulateEditField.Value}.id);
            if isempty(app.NcasesEditField_post.Value)
                app.TextField_SimulationOverview.Value = [app.TextField_SimulationOverview.Value, ...
                    sprintf('\nYou still have to define sample sizes to simulate!')];
            else 
                app.TextField_SimulationOverview.Value = [app.TextField_SimulationOverview.Value, ...
                    sprintf('\nSample sizes to simulate: %s', app.NcasesEditField_post.Value)];
            end
            M = evalin('base',sprintf('NM.analysis{1,%d}.params.TrainParam.FUSION.M',app.AnalysistosimulateEditField.Value));
            app.NM_Y = app.NM_Ys{1,M};
        end

        % Button pushed function: SimulateButton_post
        function SimulateButton_postPushed2(app, event)
             if app.loadedNMData
                M = app.NM.analysis{1,app.AnalysisListBox.Value}.params.TrainParam.FUSION.M;
                Y = app.NM.Y{1,M};
                feat = Y;
                labels = app.NM.label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            IN.Data = feat; 
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = app.simGrid;
            IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
            IN.eventprob = app.RelativedistributionlabelsEditField.Value; %unused
            IN.AUCmax = app.maxAUCEditField.Value; % unused
            IN.AUCmin = app.minAUCEditField.Value; % unused
            IN.Nbatches = app.NbatchesEditField.Value; % unused
            IN.BatchPerc = app.BatchPercEditField.Value; % unused
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value; % unused
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value; % unused
            %IN.algorithm = 
            IN.NRanalysis = app.AnalysisListBox.Value;
            IN.verbose = 0;
            IN.add2orig = app.add2orig;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            app.SimulateButton_post.Enable = 'off';
            app.SetupButton.Enable = 'off';
            app.simAppAux = simAppAux(app, app.NSims, app.NGroups, app.simGrid, app.add2orig);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.simAppAux)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sample size simulation')
            xlabel(app.UIAxes, 'N Cases')
            ylabel(app.UIAxes, 'Balanced Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.priorTab = uitab(app.TabGroup);
            app.priorTab.Title = 'prior';
            app.NfeaturesEditFieldLabel = uilabel(app.priorTab);
            app.NfeaturesEditFieldLabel.Text = 'N features';
            app.NfeaturesEditField = uieditfield(app.priorTab, 'numeric');
            app.NfeaturesEditField.ValueChangedFcn = createCallbackFcn(app, @NfeaturesEditFieldValueChanged, true);
            app.NfeaturesEditField.Value = 100;
            app.predictivemarkersEditFieldLabel = uilabel(app.priorTab);
            app.predictivemarkersEditFieldLabel.Text = '% predictive markers';
            app.predictivemarkersEditField = uieditfield(app.priorTab, 'numeric');
            app.predictivemarkersEditField.ValueChangedFcn = createCallbackFcn(app, @predictivemarkersEditFieldValueChanged, true);
            app.predictivemarkersEditField.Value = 0.2;
            app.RelativedistributionlabelsEditFieldLabel = uilabel(app.priorTab);
            app.RelativedistributionlabelsEditFieldLabel.Text = 'Relative distribution labels';
            app.RelativedistributionlabelsEditField = uieditfield(app.priorTab, 'numeric');
            app.RelativedistributionlabelsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedistributionlabelsEditFieldValueChanged, true);
            app.RelativedistributionlabelsEditField.Value = 0.5;
            app.maxAUCEditFieldLabel = uilabel(app.priorTab);
            app.maxAUCEditFieldLabel.Text = 'max AUC';
            app.maxAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.maxAUCEditField.Value = 0.7;
            app.minAUCLabel = uilabel(app.priorTab);
            app.minAUCLabel.Text = 'min AUC';
            app.minAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.minAUCEditField.Value = 0.5;
            app.NbatchesEditFieldLabel = uilabel(app.priorTab);
            app.NbatchesEditFieldLabel.Text = 'N batches';
            app.NbatchesEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchEditFieldLabel = uilabel(app.priorTab);
            app.BatchEditFieldLabel.Text = 'Batch %';
            app.BatchPercEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchPercEditField.Value = 0.1;
            app.NApercaseEditFieldLabel = uilabel(app.priorTab);
            app.NApercaseEditFieldLabel.Text = '% NA per case';
            app.NApercaseEditField = uieditfield(app.priorTab, 'numeric');
            app.NAperfeatureEditFieldLabel = uilabel(app.priorTab);
            app.NAperfeatureEditFieldLabel.Text = '% NA per feature';
            app.NAperfeatureEditField = uieditfield(app.priorTab, 'numeric');
            app.MLmodelEditFieldLabel = uilabel(app.priorTab);
            app.MLmodelEditFieldLabel.Text = 'ML model';
            app.MLmodelEditField = uieditfield(app.priorTab, 'text');
            app.MLmodelEditField.Value = 'LIBKERNSVM';
            app.NcasesEditFieldLabel = uilabel(app.priorTab);
            app.NcasesEditFieldLabel.Text = 'N cases';
            app.NcasesEditField = uieditfield(app.priorTab, 'text');
            app.NcasesEditField.Value = '[100 200 300 400]';
            app.SimulateButton = uibutton(app.priorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.postTab = uitab(app.TabGroup);
            app.postTab.Title = 'post';
            app.SimulateButton_post = uibutton(app.postTab, 'push');
            app.SimulateButton_post.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_postPushed2, true);
            app.SimulateButton_post.Text = 'Simulate';
            app.TextField_SimulationOverview = uitextarea(app.postTab);
            app.TextField_SimulationOverview.Editable = 'off';
            app.TextField_SimulationOverview.Enable = 'off';
            app.SetupButton = uibutton(app.postTab, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Change simulation plan';
            app.AnalysistosimulateListBoxLabel = uilabel(app.postTab);
            app.AnalysistosimulateListBoxLabel.Tooltip = {'Select analysis to simulate from the list'};
            app.AnalysistosimulateListBoxLabel.Text = 'Analysis to simulate';
            app.AnalysisListBox = uilistbox(app.postTab);
            app.ExportfigureButton = uibutton(app.UIFigure, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButtonPushed, true);
            app.ExportfigureButton.Visible = 'off';
            app.ExportfigureButton.Text = 'Export figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simSample_App2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end